classdef mapview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        CloseButton     matlab.ui.control.Button
        NATURALLabel    matlab.ui.control.Label
        GRAYSCALELabel  matlab.ui.control.Label
        Button          matlab.ui.control.Button
        MAPVIEWSLabel   matlab.ui.control.Label
        DMAPVIEWLabel   matlab.ui.control.Label
        Image2          matlab.ui.control.Image
        Image           matlab.ui.control.Image
    end

    
    properties (Access = public)
        WorldPanel         matlab.ui.container.Panel
    end
    
    properties (Access = private)
        VRFigure   % VR figure handle
        VRWorld    % VR world handle
    end % Description    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            grayscale()
            close(app.UIFigure)
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            natural()
            close(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Load the VR world
            app.VRWorld = vrworld('3dtest.x3d');
            open(app.VRWorld);

            % Create a VR figure and embed it in the panel
            app.VRFigure = vrfigure(app.VRWorld);
            set(app.VRFigure, 'Parent', app.WorldPanel);

            % Adjust the VR figure to fit the panel
            app.VRFigure.Position = [0, 0, app.WorldPanel.Position(3:4)];

            close(app.UIFigure)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-07-19 184321.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-07-19 184345.png');
            app.DMAPVIEWLabel = uilabel(app.UIFigure);
            app.DMAPVIEWLabel.Text = '3D MAP VIEW';
            app.MAPVIEWSLabel = uilabel(app.UIFigure);
            app.MAPVIEWSLabel.Text = 'MAP VIEWS';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, '200w.gif');
            app.Button.Text = '';
            app.GRAYSCALELabel = uilabel(app.UIFigure);
            app.GRAYSCALELabel.Text = 'GRAYSCALE';
            app.NATURALLabel = uilabel(app.UIFigure);
            app.NATURALLabel.Text = 'NATURAL';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end