classdef weather_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExitButton           matlab.ui.control.Button
        MapViewButton        matlab.ui.control.Button
        WindSpeedGauge       matlab.ui.control.Gauge
        WindSpeedGaugeLabel  matlab.ui.control.Label
        TemperatureLabel     matlab.ui.control.Label
        Switch               matlab.ui.control.Switch
        Label                matlab.ui.control.Label
        HumidityGauge        matlab.ui.control.SemicircularGauge
        HumidityGaugeLabel   matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        DropDown             matlab.ui.control.DropDown
        CheckBox_2           matlab.ui.control.CheckBox
        PastListBoxLabel_2   matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        PastListBoxLabel     matlab.ui.control.Label
        ShowWeatherButton    matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MapViewButton
        function MapViewButtonPushed(app, event)
            mapview()
            close(app.UiFigure)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: ShowWeatherButton
        function ShowWeatherButtonPushed(app, event)
            % Temperature data for the next few days in the Philippines
            temperatureData = [32, 31, 32, 31, 31, 31, 31, 31];
            dayLabels = {'Thu', 'Fri', 'Sat', 'Sun', 'Mon', 'Tue', 'Wed', 'Thu'};

            % Clear existing plots
            cla(app.UIAxes);

            % Plot the new data
            plot(app.UIAxes, temperatureData, '-o');
            title(app.UIAxes, 'Temperature Forecast for the Philippines');
            xlabel(app.UIAxes, 'Days');
            ylabel(app.UIAxes, 'Temperature (°C)');
            xticks(app.UIAxes, 1:8);
            xticklabels(app.UIAxes, dayLabels);
            grid(app.UIAxes, 'on');
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Weather Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ShowWeatherButton = uibutton(app.UIFigure, 'push');
            app.ShowWeatherButton.ButtonPushedFcn = createCallbackFcn(app, @ShowWeatherButtonPushed, true);
            app.ShowWeatherButton.Text = 'Show Weather';
            app.PastListBoxLabel = uilabel(app.UIFigure);
            app.PastListBoxLabel.Text = 'Past:';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.PastListBoxLabel_2 = uilabel(app.UIFigure);
            app.PastListBoxLabel_2.Text = 'Next:';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1 Day', '1 Week', '1 Month'};
            app.DropDown.Value = '1 Day';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'1 Day', '1 Week', '1 Month'};
            app.DropDown_2.Value = '1 Day';
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.HumidityGauge = uigauge(app.UIFigure, 'semicircular');
            app.Label = uilabel(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Celsius', 'Fahrenheight'};
            app.Switch.Value = 'Celsius';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.WindSpeedGaugeLabel = uilabel(app.UIFigure);
            app.WindSpeedGaugeLabel.Text = 'Wind Speed';
            app.WindSpeedGauge = uigauge(app.UIFigure, 'circular');
            app.MapViewButton = uibutton(app.UIFigure, 'push');
            app.MapViewButton.ButtonPushedFcn = createCallbackFcn(app, @MapViewButtonPushed, true);
            app.MapViewButton.Text = 'Map View';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = weather_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end